classdef Grating_Utilities < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TransmissionGratingTab          matlab.ui.container.Tab
        VerticalOpaqueSlitRatioEditField  matlab.ui.control.NumericEditField
        VerticalOpaqueSlitRatioEditFieldLabel  matlab.ui.control.Label
        HorizontalOpaqueSlitRatioEditField  matlab.ui.control.NumericEditField
        HorizontalOpaqueSlitRatioEditFieldLabel  matlab.ui.control.Label
        IntensityProfileDropDown        matlab.ui.control.DropDown
        IntensityProfileDropDownLabel   matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        Maxm_yEditField                 matlab.ui.control.NumericEditField
        Maxm_yEditFieldLabel            matlab.ui.control.Label
        Maxm_xEditField                 matlab.ui.control.NumericEditField
        Maxm_xEditFieldLabel            matlab.ui.control.Label
        DeltaypxEditField               matlab.ui.control.NumericEditField
        DeltaypxEditFieldLabel          matlab.ui.control.Label
        Theta_ydegEditField             matlab.ui.control.NumericEditField
        Theta_ydegEditFieldLabel        matlab.ui.control.Label
        m_yEditField                    matlab.ui.control.NumericEditField
        m_yEditFieldLabel               matlab.ui.control.Label
        DeltaxpxEditField               matlab.ui.control.NumericEditField
        DeltaxpxEditFieldLabel          matlab.ui.control.Label
        Theta_xdegEditField             matlab.ui.control.NumericEditField
        Theta_xdegLabel                 matlab.ui.control.Label
        m_xEditField                    matlab.ui.control.NumericEditField
        m_xEditFieldLabel               matlab.ui.control.Label
        MaxTheta_ydegEditField          matlab.ui.control.NumericEditField
        MaxTheta_ydegEditFieldLabel     matlab.ui.control.Label
        MaxTheta_xdegEditField          matlab.ui.control.NumericEditField
        MaxTheta_xdegEditFieldLabel     matlab.ui.control.Label
        BeamsizeumEditField             matlab.ui.control.NumericEditField
        BeamsizeumEditFieldLabel        matlab.ui.control.Label
        CCDDistancemmEditField          matlab.ui.control.NumericEditField
        CCDDistancemmEditFieldLabel     matlab.ui.control.Label
        CCDPixelSizeumEditField         matlab.ui.control.NumericEditField
        CCDPixelSizeumEditFieldLabel    matlab.ui.control.Label
        CCDHeightpxEditField            matlab.ui.control.NumericEditField
        CCDHeightpxEditFieldLabel       matlab.ui.control.Label
        CCDWidthpxEditField             matlab.ui.control.NumericEditField
        CCDWidthpxEditFieldLabel        matlab.ui.control.Label
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        d_ynmEditField                  matlab.ui.control.NumericEditField
        d_ynmEditFieldLabel             matlab.ui.control.Label
        VerticalGratingDensitylmmEditField  matlab.ui.control.NumericEditField
        VerticalGratingDensitylmmEditFieldLabel  matlab.ui.control.Label
        d_xnmEditField                  matlab.ui.control.NumericEditField
        d_xnmEditFieldLabel             matlab.ui.control.Label
        HorizontalGratingDensitylmmEditField  matlab.ui.control.NumericEditField
        HorizontalGratingDensitylmmEditFieldLabel  matlab.ui.control.Label
        ConicalGratingTab               matlab.ui.container.Tab
        RotationDropDown                matlab.ui.control.DropDown
        RotationDropDownLabel           matlab.ui.control.Label
        PlotOptionsLabel                matlab.ui.control.Label
        CenteratConeTopCheckBox         matlab.ui.control.CheckBox
        PlotButton_2                    matlab.ui.control.Button
        CalculateButton_2               matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        MaxdegEditField                 matlab.ui.control.NumericEditField
        MaxdegEditFieldLabel            matlab.ui.control.Label
        MaxmEditField                   matlab.ui.control.NumericEditField
        MaxmEditFieldLabel              matlab.ui.control.Label
        DiffractedAzimuthalAngledegEditField  matlab.ui.control.NumericEditField
        DiffractedAzimuthalAngledegEditFieldLabel  matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        BeamsizeumEditField_2           matlab.ui.control.NumericEditField
        BeamsizeumEditField_2Label      matlab.ui.control.Label
        CCDDistancemmEditField_2        matlab.ui.control.NumericEditField
        CCDDistancemmEditField_2Label   matlab.ui.control.Label
        CCDPixelSizeumEditField_2       matlab.ui.control.NumericEditField
        CCDPixelSizeumEditField_2Label  matlab.ui.control.Label
        CCDHeightpxEditField_2          matlab.ui.control.NumericEditField
        CCDHeightpxEditField_2Label     matlab.ui.control.Label
        CCDWidthpxEditField_2           matlab.ui.control.NumericEditField
        CCDWidthpxEditField_2Label      matlab.ui.control.Label
        WavelengthnmEditField_2         matlab.ui.control.NumericEditField
        WavelengthnmEditField_2Label    matlab.ui.control.Label
        IncidentAzimuthalAngledegEditField  matlab.ui.control.NumericEditField
        IncidentAzimuthalAngledegEditFieldLabel  matlab.ui.control.Label
        IncidentAngledegEditField       matlab.ui.control.NumericEditField
        IncidentAngledegEditFieldLabel  matlab.ui.control.Label
        dnmEditField                    matlab.ui.control.NumericEditField
        dnmEditFieldLabel               matlab.ui.control.Label
        GratingDensitylmmEditField      matlab.ui.control.NumericEditField
        GratingDensitylmmEditFieldLabel  matlab.ui.control.Label
        HarmonicsTab                    matlab.ui.container.Tab
        ConicalGratingPanel             matlab.ui.container.Panel
        RotationDropDown_2              matlab.ui.control.DropDown
        RotationDropDown_2Label         matlab.ui.control.Label
        PlotOptionsLabel_2              matlab.ui.control.Label
        CenteratConeTopCheckBox_2       matlab.ui.control.CheckBox
        IncidentAzimuthalAngledegEditField_2  matlab.ui.control.NumericEditField
        IncidentAzimuthalAngledegEditField_2Label  matlab.ui.control.Label
        IncidentAngledegEditField_2     matlab.ui.control.NumericEditField
        IncidentAngledegEditField_2Label  matlab.ui.control.Label
        dnmEditField_2                  matlab.ui.control.NumericEditField
        dnmEditField_2Label             matlab.ui.control.Label
        GratingDensitylmmEditField_2    matlab.ui.control.NumericEditField
        GratingDensitylmmEditField_2Label  matlab.ui.control.Label
        LabelHarmonicOrdersCheckBox     matlab.ui.control.CheckBox
        PlotButton_3                    matlab.ui.control.Button
        FirstorderdiffractiononlyCheckBox  matlab.ui.control.CheckBox
        CCDPanel                        matlab.ui.container.Panel
        BeamsizeumEditField_3           matlab.ui.control.NumericEditField
        BeamsizeumEditField_3Label      matlab.ui.control.Label
        CCDDistancemmEditField_3        matlab.ui.control.NumericEditField
        CCDDistancemmEditField_3Label   matlab.ui.control.Label
        CCDPixelSizeumEditField_3       matlab.ui.control.NumericEditField
        CCDPixelSizeumEditField_3Label  matlab.ui.control.Label
        CCDHeightpxEditField_3          matlab.ui.control.NumericEditField
        CCDHeightpxEditField_3Label     matlab.ui.control.Label
        CCDWidthpxEditField_3           matlab.ui.control.NumericEditField
        CCDWidthpxEditField_3Label      matlab.ui.control.Label
        HarmonicsPanel                  matlab.ui.container.Panel
        HighestOrderEditField           matlab.ui.control.NumericEditField
        HighestOrderEditFieldLabel      matlab.ui.control.Label
        LowestOrderEditField            matlab.ui.control.NumericEditField
        LowestOrderEditFieldLabel       matlab.ui.control.Label
        DrivenWavelengthnmEditField     matlab.ui.control.NumericEditField
        DrivenWavelengthnmEditFieldLabel  matlab.ui.control.Label
        GratingTypeDropDown             matlab.ui.control.DropDown
        GratingTypeDropDownLabel        matlab.ui.control.Label
        TransmissionGratingPanel        matlab.ui.container.Panel
        VerticalOpaqueSlitRatioEditField_2  matlab.ui.control.NumericEditField
        VerticalOpaqueSlitRatioEditField_2Label  matlab.ui.control.Label
        HorizontalOpaqueSlitRatioEditField_2  matlab.ui.control.NumericEditField
        HorizontalOpaqueSlitRatioEditField_2Label  matlab.ui.control.Label
        IntensityProfileDropDown_2      matlab.ui.control.DropDown
        IntensityProfileDropDown_2Label  matlab.ui.control.Label
        d_ynmEditField_2                matlab.ui.control.NumericEditField
        d_ynmEditField_2Label           matlab.ui.control.Label
        VerticalGratingDensitylmmEditField_2  matlab.ui.control.NumericEditField
        VerticalGratingDensitylmmEditField_2Label  matlab.ui.control.Label
        d_xnmEditField_2                matlab.ui.control.NumericEditField
        d_xnmEditField_2Label           matlab.ui.control.Label
        HorizontalGratingDensitylmmEditField_2  matlab.ui.control.NumericEditField
        HorizontalGratingDensitylmmEditField_2Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CenterList
        IntensityList
    end
    
    methods (Access = private)
        
        function CalculateTransmissionGrating(app)
            CCDW = app.CCDWidthpxEditField.Value;
            CCDH = app.CCDHeightpxEditField.Value;
            PixelSize = app.CCDPixelSizeumEditField.Value * 1e-6;
            CCDD = app.CCDDistancemmEditField.Value * 1e-3;
            
            d_x = app.d_xnmEditField.Value * 1e-9;
            d_y = app.d_ynmEditField.Value * 1e-9;
            Lambda = app.WavelengthnmEditField.Value * 1e-9;

            Theta_x_max = atan(CCDW*PixelSize/(2*CCDD));
            Theta_y_max = atan(CCDH*PixelSize/(2*CCDD));
            
            app.MaxTheta_xdegEditField.Value = rad2deg(Theta_x_max);
            app.MaxTheta_ydegEditField.Value = rad2deg(Theta_y_max);
            
            [m_x_max, ~, ~, ~] = CalcGratingFormula(0,Lambda,d_x,Theta_x_max);
            [m_y_max, ~, ~, ~] = CalcGratingFormula(0,Lambda,d_y,Theta_y_max);

            app.Maxm_xEditField.Value = floor(m_x_max);
            app.Maxm_yEditField.Value = floor(m_y_max);

            m_x = app.m_xEditField.Value;
            m_y = app.m_yEditField.Value;

            [~, ~, ~, Theta_x] = CalcGratingFormula(m_x, Lambda, d_x, 0);
            [~, ~, ~, Theta_y] = CalcGratingFormula(m_y, Lambda, d_y, 0);

            Delta_x = tan(Theta_x) * CCDD / PixelSize;
            Delta_y = tan(Theta_y) * CCDD / PixelSize;

            app.Theta_xdegEditField.Value = rad2deg(Theta_x);
            app.Theta_ydegEditField.Value = rad2deg(Theta_y);
            app.DeltaxpxEditField.Value = Delta_x;
            app.DeltaypxEditField.Value = Delta_y;

            N_x = ceil(m_x_max) * 2 + 1;
            N_y = ceil(m_y_max) * 2 + 1;

            BeamSize = app.BeamsizeumEditField.Value * 1e-6;
            NGrating_x = round(BeamSize / d_x / 2);
            NGrating_y = round(BeamSize / d_y / 2);
            OSRatio_x = app.HorizontalOpaqueSlitRatioEditField.Value;
            OSRatio_y = app.VerticalOpaqueSlitRatioEditField.Value;

            app.CenterList = zeros(N_x * N_y, 2);
            app.IntensityList = zeros(N_x * N_y, 1);

            for i = 1:N_x
                for j = 1:N_y
                    if app.IntensityProfileDropDown.Value == '1'
                        IntensityProfile = "Uniform";
                    elseif app.IntensityProfileDropDown.Value == '2'
                        IntensityProfile = "MultipleSlit";
                    end
                    m_x = ceil(m_x_max) - i + 1;    
                    m_y = ceil(m_y_max) - j + 1;
                    [Delta, Intensity] = CalcTransmissionGrating(m_x, m_y, d_x, d_y, Lambda, CCDD, IntensityProfile, NGrating_x, NGrating_y, OSRatio_x, OSRatio_y);
                    app.CenterList((i-1)*N_y+j,:) = Delta;
                    app.IntensityList((i-1)*N_y+j) = Intensity;
                end
            end
        end
        
        function CalculateConicalGrating(app)
            CCDD = app.CCDDistancemmEditField_2.Value * 1e-3;

            d = app.dnmEditField.Value * 1e-9;
            Lambda = app.WavelengthnmEditField_2.Value * 1e-9;

            Gamma = deg2rad(app.IncidentAngledegEditField.Value);
            Alpha = deg2rad(app.IncidentAzimuthalAngledegEditField.Value);

            % Calculate every possible order for now
            Beta_max = pi/2;
            Beta_min = -pi/2;

            [m_max, ~, ~, ~, ~, ~] = CalcConicalGratingFormula(missing, Lambda, d, Gamma, Alpha, Beta_max);
            [m_min, ~, ~, ~, ~, ~] = CalcConicalGratingFormula(missing, Lambda, d, Gamma, Alpha, Beta_min);

            mE = app.mEditField.Value;

            [~, ~, ~, ~, ~, Beta] = CalcConicalGratingFormula(mE, Lambda, d, Gamma, Alpha, missing);
            app.DiffractedAzimuthalAngledegEditField.Value = rad2deg(Beta);

            N = floor(m_max) + floor(abs(m_min)) + 1;
            
            DeltaList = zeros(N, 2);

            CenteratConeTop = app.CenteratConeTopCheckBox.Value;
            Rotation = app.RotationDropDown.Value;

            for i = 1:N
                m = floor(m_max) - i + 1;
                DeltaList(i, :) = CalcConicalGrating(m, d, Lambda, Gamma, Alpha, CCDD, CenteratConeTop, Rotation);
            end
            
            app.CenterList = DeltaList;
            app.IntensityList = 100 * ones(N, 1);
        end

        function CalculateHarmonics(app)
%             CCDW = app.CCDWidthpxEditField_3.Value;
%             CCDH = app.CCDHeightpxEditField_3.Value;
%             PixelSize = app.CCDPixelSizeumEditField_3.Value * 1e-6;
            CCDD = app.CCDDistancemmEditField_3.Value * 1e-3;
%             BeamSize = app.BeamsizeumEditField_3.Value * 1e-6;

            NH_min = floor(app.LowestOrderEditField.Value / 2) * 2 + 1;
            NH_max = ceil(app.HighestOrderEditField.Value / 2) * 2 - 1;   
            N_Harmonics = round((NH_max - NH_min) / 2 + 1);

            DrivenLambda = app.DrivenWavelengthnmEditField.Value * 1e-9;

            HarmonicList = zeros(N_Harmonics, 1);

            for i=1:N_Harmonics
                HarmonicList(i) = DrivenLambda / (NH_min + 2 * (i - 1));
            end

            app.CenterList = zeros(N_Harmonics * 2 + 1, 2);
            app.IntensityList = 100 * ones(N_Harmonics * 2 + 1, 1);

            ms = [0,-1,1];

            if app.GratingTypeDropDown.Value == "Transmission"
                d_x = app.d_xnmEditField_2.Value * 1e-9;
%                 d_y = app.d_ynmEditField_2.Value * 1e-9;       
                d_y = 1e-6;
                
                for k=1:3
                    for i=1:N_Harmonics
                        m = ms(k);
                        [Delta, ~] = CalcTransmissionGrating(m, 0, d_x, d_y, HarmonicList(i), CCDD);
                        if m == 0
                            app.CenterList(1,:) = Delta;
                            break
                        else
                            app.CenterList(1+(k-2)*N_Harmonics+i,:) = Delta;
                        end
                    end                        
                end
                

            elseif app.GratingTypeDropDown.Value == "Conical"
                d = app.dnmEditField_2.Value * 1e-9;
                Gamma = deg2rad(app.IncidentAngledegEditField_2.Value);
                Alpha = deg2rad(app.IncidentAzimuthalAngledegEditField_2.Value);

                CenteratConeTop = app.CenteratConeTopCheckBox_2.Value;
                Rotation = app.RotationDropDown_2.Value;
                
                for k=1:3
                    for i=1:N_Harmonics
                        m = ms(k);
                        Delta = CalcConicalGrating(m, d, HarmonicList(i), Gamma, Alpha, CCDD, CenteratConeTop, Rotation);

                        if m == 0
                            app.CenterList(1,:) = Delta;
                            break
                        else
                            if ~isreal(Delta)
                                disp(m);
                                disp(Gamma);
                                disp(Alpha);
                                disp(Delta);
                                Delta = [0 0];
                                app.IntensityList(1+(k-2)*N_Harmonics+i,:) = 0;
                            end
                            app.CenterList(1+(k-2)*N_Harmonics+i,:) = Delta;
                        end
                    end                        
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.CalculateTransmissionGrating();
        end

        % Value changed function: HorizontalGratingDensitylmmEditField
        function HorizontalGratingDensitylmmEditFieldValueChanged(app, event)
            value = app.HorizontalGratingDensitylmmEditField.Value;
            app.d_xnmEditField.Value = 1e6/value;
        end

        % Value changed function: d_xnmEditField
        function d_xnmEditFieldValueChanged(app, event)
            value = app.d_xnmEditField.Value;
            app.HorizontalGratingDensitylmmEditField.Value = 1e6/value;
        end

        % Value changed function: VerticalGratingDensitylmmEditField
        function VerticalGratingDensitylmmEditFieldValueChanged(app, event)
            value = app.VerticalGratingDensitylmmEditField.Value;
            app.d_ynmEditField.Value = 1e6/value;
        end

        % Value changed function: d_ynmEditField
        function d_ynmEditFieldValueChanged(app, event)
            value = app.d_ynmEditField.Value;
            app.VerticalGratingDensitylmmEditField.Value = 1e6/value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.CalculateTransmissionGrating();
            f = figure(1);
            ax = axes('Parent',f);
            BeamListScPlot(ax, app.CenterList, app.CCDWidthpxEditField.Value, app.CCDHeightpxEditField.Value, app.CCDPixelSizeumEditField.Value * 1e-6, app.BeamsizeumEditField.Value*1e-6, app.IntensityList);
            title(ax, "Image on CCD");
            drawnow;
            figure(2);
            ax2_1 = subplot(2,1,1);
            BeamListDistPlot(ax2_1, app.CenterList, app.CCDHeightpxEditField.Value / 2, 1, app.CCDWidthpxEditField.Value, app.CCDHeightpxEditField.Value, app.CCDPixelSizeumEditField.Value * 1e-6, app.BeamsizeumEditField.Value*1e-6, app.IntensityList);
            title(ax2_1, "Intensity Profile at x = 0");
            ax2_2 = subplot(2,1,2);
            BeamListDistPlot(ax2_2, app.CenterList, app.CCDWidthpxEditField.Value / 2, 2, app.CCDWidthpxEditField.Value, app.CCDHeightpxEditField.Value, app.CCDPixelSizeumEditField.Value * 1e-6, app.BeamsizeumEditField.Value*1e-6, app.IntensityList);
            title(ax2_2, "Intensity Profile at y = 0");
            drawnow;
        end

        % Value changed function: IntensityProfileDropDown
        function IntensityProfileDropDownValueChanged(app, event)
            value = app.IntensityProfileDropDown.Value;
            switch value
                case '2'
                    app.HorizontalOpaqueSlitRatioEditFieldLabel.Visible = "on";
                    app.VerticalOpaqueSlitRatioEditFieldLabel.Visible = "on";
                    app.HorizontalOpaqueSlitRatioEditField.Visible = "on";
                    app.VerticalOpaqueSlitRatioEditField.Visible = "on";
                otherwise
                    app.HorizontalOpaqueSlitRatioEditFieldLabel.Visible = "off";
                    app.VerticalOpaqueSlitRatioEditFieldLabel.Visible = "off";
                    app.HorizontalOpaqueSlitRatioEditField.Visible = "off";
                    app.VerticalOpaqueSlitRatioEditField.Visible = "off";
            end
        end

        % Value changed function: GratingDensitylmmEditField
        function GratingDensitylmmEditFieldValueChanged(app, event)
            value = app.GratingDensitylmmEditField.Value;
            app.dnmEditField.Value = 1e6/value;
        end

        % Value changed function: dnmEditField
        function dnmEditFieldValueChanged(app, event)
            value = app.dnmEditField.Value;
            app.GratingDensitylmmEditField.Value = 1e6/value;
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            app.CalculateConicalGrating();
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            app.CalculateConicalGrating();
            f = figure(3);
            ax = axes('Parent',f);
            BeamListScPlot(ax, app.CenterList, app.CCDWidthpxEditField_2.Value, app.CCDHeightpxEditField_2.Value, app.CCDPixelSizeumEditField_2.Value * 1e-6, app.BeamsizeumEditField_2.Value*1e-6, app.IntensityList);
            title(ax, "Image on CCD");
            drawnow;
        end

        % Value changed function: GratingTypeDropDown
        function GratingTypeDropDownValueChanged(app, event)
            value = app.GratingTypeDropDown.Value;
            if value == "Transmission"
                app.TransmissionGratingPanel.Enable = "on";
                app.TransmissionGratingPanel.Visible = true;
                app.ConicalGratingPanel.Enable = "off";
                app.ConicalGratingPanel.Visible = false;
            elseif value == "Conical"
                app.TransmissionGratingPanel.Enable = "off";
                app.TransmissionGratingPanel.Visible = false;
                app.ConicalGratingPanel.Enable = "on";
                app.ConicalGratingPanel.Visible = true;
            end
        end

        % Value changed function: IntensityProfileDropDown_2
        function IntensityProfileDropDown_2ValueChanged(app, event)
            value = app.IntensityProfileDropDown_2.Value;
            switch value
                case '2'
                    app.HorizontalOpaqueSlitRatioEditField_2Label.Visible = "on";
                    app.VerticalOpaqueSlitRatioEditField_2Label.Visible = "on";
                    app.HorizontalOpaqueSlitRatioEditField_2.Visible = "on";
                    app.VerticalOpaqueSlitRatioEditField_2.Visible = "on";
                otherwise
                    app.HorizontalOpaqueSlitRatioEditField_2Label.Visible = "off";
                    app.VerticalOpaqueSlitRatioEditField_2Label.Visible = "off";
                    app.HorizontalOpaqueSlitRatioEditField_2.Visible = "off";
                    app.VerticalOpaqueSlitRatioEditField_2.Visible = "off";
            end
        end

        % Value changed function: HorizontalGratingDensitylmmEditField_2
        function HorizontalGratingDensitylmmEditField_2ValueChanged(app, event)
            value = app.HorizontalGratingDensitylmmEditField_2.Value;
            app.d_xnmEditField_2.Value = 1e6/value;
        end

        % Value changed function: d_xnmEditField_2
        function d_xnmEditField_2ValueChanged(app, event)
            value = app.d_xnmEditField_2.Value;
            app.HorizontalGratingDensitylmmEditField_2.Value =1e6/value;
        end

        % Value changed function: VerticalGratingDensitylmmEditField_2
        function VerticalGratingDensitylmmEditField_2ValueChanged(app, event)
            value = app.VerticalGratingDensitylmmEditField_2.Value;
            app.d_ynmEditField_2.Value = 1e6/value;
        end

        % Value changed function: d_ynmEditField_2
        function d_ynmEditField_2ValueChanged(app, event)
            value = app.d_ynmEditField_2.Value;
            app.VerticalGratingDensitylmmEditField_2.Value = 1e6/value;
        end

        % Value changed function: GratingDensitylmmEditField_2
        function GratingDensitylmmEditField_2ValueChanged(app, event)
            value = app.GratingDensitylmmEditField_2.Value;
            app.dnmEditField_2.Value = 1e6/value;
        end

        % Value changed function: dnmEditField_2
        function dnmEditField_2ValueChanged(app, event)
            value = app.dnmEditField_2.Value;
            app.GratingDensitylmmEditField_2.Value = 1e6/value;
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            app.CalculateHarmonics();
            f = figure(4);
            ax = axes('Parent',f);
            
            CCDW = app.CCDWidthpxEditField_3.Value;
            CCDH = app.CCDHeightpxEditField_3.Value;
            PixelSize = app.CCDPixelSizeumEditField_3.Value * 1e-6;
            BeamSize = app.BeamsizeumEditField_3.Value * 1e-6;

            BeamListScPlot(ax, app.CenterList, CCDW, CCDH, PixelSize, BeamSize, app.IntensityList);
            title(ax, "Image on CCD");

            if app.LabelHarmonicOrdersCheckBox.Value
                NH_min = floor(app.LowestOrderEditField.Value / 2) * 2 + 1;
                NH_max = ceil(app.HighestOrderEditField.Value / 2) * 2 - 1;  
                N_Harmonics = round((NH_max - NH_min) / 2 + 1);

                labels = cell(1, N_Harmonics * 2 + 1);

                for i = 1:N_Harmonics
                    labels{i+1} = int2str(NH_min+2*(i-1));
                end

                centerIdxList = round((app.CenterList ./ PixelSize) + [CCDW/2, CCDH/2]);
                text(centerIdxList(:,1), centerIdxList(:,2), labels, 'Color','white', 'FontWeight','bold');

                
%                 text(ax, app.CenterList(1,1), app.CenterList(1,2), "0", 'Color','white','FontSize',200);
            end
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TransmissionGratingTab = uitab(app.TabGroup);
            app.TransmissionGratingTab.Title = 'Transmission Grating';
            app.HorizontalGratingDensitylmmEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.HorizontalGratingDensitylmmEditFieldLabel.Text = 'Horizontal Grating Density (l/mm)';
            app.HorizontalGratingDensitylmmEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.HorizontalGratingDensitylmmEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalGratingDensitylmmEditFieldValueChanged, true);
            app.d_xnmEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.d_xnmEditFieldLabel.Text = 'd_x (nm)';
            app.d_xnmEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.d_xnmEditField.ValueChangedFcn = createCallbackFcn(app, @d_xnmEditFieldValueChanged, true);
            app.VerticalGratingDensitylmmEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.VerticalGratingDensitylmmEditFieldLabel.Text = 'Vertical Grating Density (l/mm)';
            app.VerticalGratingDensitylmmEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.VerticalGratingDensitylmmEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalGratingDensitylmmEditFieldValueChanged, true);
            app.d_ynmEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.d_ynmEditFieldLabel.Text = 'd_y (nm)';
            app.d_ynmEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.d_ynmEditField.ValueChangedFcn = createCallbackFcn(app, @d_ynmEditFieldValueChanged, true);
            app.WavelengthnmEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.CCDWidthpxEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.CCDWidthpxEditFieldLabel.Text = 'CCD Width (px)';
            app.CCDWidthpxEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.CCDHeightpxEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.CCDHeightpxEditFieldLabel.Text = 'CCD Height (px)';
            app.CCDHeightpxEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.CCDPixelSizeumEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.CCDPixelSizeumEditFieldLabel.Text = 'CCD Pixel Size (um)';
            app.CCDPixelSizeumEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.CCDDistancemmEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.CCDDistancemmEditFieldLabel.Text = 'CCD Distance (mm)';
            app.CCDDistancemmEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.BeamsizeumEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.BeamsizeumEditFieldLabel.Text = 'Beam size (um)';
            app.BeamsizeumEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.MaxTheta_xdegEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.MaxTheta_xdegEditFieldLabel.Text = 'Max Theta_x (deg)';
            app.MaxTheta_xdegEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.MaxTheta_ydegEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.MaxTheta_ydegEditFieldLabel.Text = 'Max Theta_y (deg)';
            app.MaxTheta_ydegEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.m_xEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.m_xEditFieldLabel.Text = 'm_x';
            app.m_xEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.m_xEditField.RoundFractionalValues = 'on';
            app.m_xEditField.Value = 1;
            app.Theta_xdegLabel = uilabel(app.TransmissionGratingTab);
            app.Theta_xdegLabel.Text = 'Theta_x (deg)';
            app.Theta_xdegEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.DeltaxpxEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.DeltaxpxEditFieldLabel.Text = 'Delta x (px)';
            app.DeltaxpxEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.m_yEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.m_yEditFieldLabel.Text = 'm_y';
            app.m_yEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.m_yEditField.RoundFractionalValues = 'on';
            app.m_yEditField.Value = 1;
            app.Theta_ydegEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.Theta_ydegEditFieldLabel.Text = 'Theta_y (deg)';
            app.Theta_ydegEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.DeltaypxEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.DeltaypxEditFieldLabel.Text = 'Delta y (px)';
            app.DeltaypxEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.Maxm_xEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.Maxm_xEditFieldLabel.Text = 'Max m_x';
            app.Maxm_xEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.Maxm_xEditField.RoundFractionalValues = 'on';
            app.Maxm_yEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.Maxm_yEditFieldLabel.Text = 'Max m_y';
            app.Maxm_yEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.Maxm_yEditField.RoundFractionalValues = 'on';
            app.CalculateButton = uibutton(app.TransmissionGratingTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PlotButton = uibutton(app.TransmissionGratingTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.IntensityProfileDropDownLabel = uilabel(app.TransmissionGratingTab);
            app.IntensityProfileDropDownLabel.Text = 'Intensity Profile';
            app.IntensityProfileDropDown = uidropdown(app.TransmissionGratingTab);
            app.IntensityProfileDropDown.Items = {'Uniform', 'Multiple-Slit'};
            app.IntensityProfileDropDown.ItemsData = {'1', '2'};
            app.IntensityProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @IntensityProfileDropDownValueChanged, true);
            app.IntensityProfileDropDown.Value = '1';
            app.HorizontalOpaqueSlitRatioEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.HorizontalOpaqueSlitRatioEditFieldLabel.Visible = 'off';
            app.HorizontalOpaqueSlitRatioEditFieldLabel.Text = 'Horizontal Opaque/Slit Ratio';
            app.HorizontalOpaqueSlitRatioEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.HorizontalOpaqueSlitRatioEditField.Visible = 'off';
            app.HorizontalOpaqueSlitRatioEditField.Value = 1;
            app.VerticalOpaqueSlitRatioEditFieldLabel = uilabel(app.TransmissionGratingTab);
            app.VerticalOpaqueSlitRatioEditFieldLabel.Visible = 'off';
            app.VerticalOpaqueSlitRatioEditFieldLabel.Text = 'Vertical Opaque/Slit Ratio';
            app.VerticalOpaqueSlitRatioEditField = uieditfield(app.TransmissionGratingTab, 'numeric');
            app.VerticalOpaqueSlitRatioEditField.Visible = 'off';
            app.VerticalOpaqueSlitRatioEditField.Value = 1;
            app.ConicalGratingTab = uitab(app.TabGroup);
            app.ConicalGratingTab.Title = 'Conical Grating';
            app.GratingDensitylmmEditFieldLabel = uilabel(app.ConicalGratingTab);
            app.GratingDensitylmmEditFieldLabel.Text = 'Grating Density (l/mm)';
            app.GratingDensitylmmEditField = uieditfield(app.ConicalGratingTab, 'numeric');
            app.GratingDensitylmmEditField.ValueChangedFcn = createCallbackFcn(app, @GratingDensitylmmEditFieldValueChanged, true);
            app.dnmEditFieldLabel = uilabel(app.ConicalGratingTab);
            app.dnmEditFieldLabel.Text = 'd (nm)';
            app.dnmEditField = uieditfield(app.ConicalGratingTab, 'numeric');
            app.dnmEditField.ValueChangedFcn = createCallbackFcn(app, @dnmEditFieldValueChanged, true);
            app.IncidentAngledegEditFieldLabel = uilabel(app.ConicalGratingTab);
            app.IncidentAngledegEditFieldLabel.Text = 'Incident Angle γ (deg)';
            app.IncidentAngledegEditField = uieditfield(app.ConicalGratingTab, 'numeric');
            app.IncidentAzimuthalAngledegEditFieldLabel = uilabel(app.ConicalGratingTab);
            app.IncidentAzimuthalAngledegEditFieldLabel.Text = 'Incident Azimuthal Angle α (deg)';
            app.IncidentAzimuthalAngledegEditField = uieditfield(app.ConicalGratingTab, 'numeric');
            app.WavelengthnmEditField_2Label = uilabel(app.ConicalGratingTab);
            app.WavelengthnmEditField_2Label.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField_2 = uieditfield(app.ConicalGratingTab, 'numeric');
            app.CCDWidthpxEditField_2Label = uilabel(app.ConicalGratingTab);
            app.CCDWidthpxEditField_2Label.Text = 'CCD Width (px)';
            app.CCDWidthpxEditField_2 = uieditfield(app.ConicalGratingTab, 'numeric');
            app.CCDHeightpxEditField_2Label = uilabel(app.ConicalGratingTab);
            app.CCDHeightpxEditField_2Label.Text = 'CCD Height (px)';
            app.CCDHeightpxEditField_2 = uieditfield(app.ConicalGratingTab, 'numeric');
            app.CCDPixelSizeumEditField_2Label = uilabel(app.ConicalGratingTab);
            app.CCDPixelSizeumEditField_2Label.Text = 'CCD Pixel Size (um)';
            app.CCDPixelSizeumEditField_2 = uieditfield(app.ConicalGratingTab, 'numeric');
            app.CCDDistancemmEditField_2Label = uilabel(app.ConicalGratingTab);
            app.CCDDistancemmEditField_2Label.Text = 'CCD Distance (mm)';
            app.CCDDistancemmEditField_2 = uieditfield(app.ConicalGratingTab, 'numeric');
            app.BeamsizeumEditField_2Label = uilabel(app.ConicalGratingTab);
            app.BeamsizeumEditField_2Label.Text = 'Beam size (um)';
            app.BeamsizeumEditField_2 = uieditfield(app.ConicalGratingTab, 'numeric');
            app.mEditFieldLabel = uilabel(app.ConicalGratingTab);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.ConicalGratingTab, 'numeric');
            app.mEditField.RoundFractionalValues = 'on';
            app.mEditField.Value = 1;
            app.DiffractedAzimuthalAngledegEditFieldLabel = uilabel(app.ConicalGratingTab);
            app.DiffractedAzimuthalAngledegEditFieldLabel.Text = 'Diffracted Azimuthal Angle β (deg)';
            app.DiffractedAzimuthalAngledegEditField = uieditfield(app.ConicalGratingTab, 'numeric');
            app.MaxmEditFieldLabel = uilabel(app.ConicalGratingTab);
            app.MaxmEditFieldLabel.Enable = 'off';
            app.MaxmEditFieldLabel.Visible = 'off';
            app.MaxmEditFieldLabel.Text = 'Max m';
            app.MaxmEditField = uieditfield(app.ConicalGratingTab, 'numeric');
            app.MaxmEditField.RoundFractionalValues = 'on';
            app.MaxmEditField.Enable = 'off';
            app.MaxmEditField.Visible = 'off';
            app.MaxmEditField.Value = 1;
            app.MaxdegEditFieldLabel = uilabel(app.ConicalGratingTab);
            app.MaxdegEditFieldLabel.Enable = 'off';
            app.MaxdegEditFieldLabel.Visible = 'off';
            app.MaxdegEditFieldLabel.Text = 'Max β (deg)';
            app.MaxdegEditField = uieditfield(app.ConicalGratingTab, 'numeric');
            app.MaxdegEditField.Enable = 'off';
            app.MaxdegEditField.Visible = 'off';
            app.Image = uiimage(app.ConicalGratingTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'echelle-grating.png');
            app.CalculateButton_2 = uibutton(app.ConicalGratingTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.PlotButton_2 = uibutton(app.ConicalGratingTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.CenteratConeTopCheckBox = uicheckbox(app.ConicalGratingTab);
            app.CenteratConeTopCheckBox.Text = 'Center at Cone Top';
            app.PlotOptionsLabel = uilabel(app.ConicalGratingTab);
            app.PlotOptionsLabel.Text = 'Plot Options';
            app.RotationDropDownLabel = uilabel(app.ConicalGratingTab);
            app.RotationDropDownLabel.Text = 'Rotation';
            app.RotationDropDown = uidropdown(app.ConicalGratingTab);
            app.RotationDropDown.Items = {'0°', '90°', '180°', '270°'};
            app.RotationDropDown.ItemsData = [0 90 180 270];
            app.RotationDropDown.Value = 0;
            app.HarmonicsTab = uitab(app.TabGroup);
            app.HarmonicsTab.Title = 'Harmonics';
            app.TransmissionGratingPanel = uipanel(app.HarmonicsTab);
            app.TransmissionGratingPanel.Title = 'Transmission Grating';
            app.HorizontalGratingDensitylmmEditField_2Label = uilabel(app.TransmissionGratingPanel);
            app.HorizontalGratingDensitylmmEditField_2Label.Text = 'Horizontal Grating Density (l/mm)';
            app.HorizontalGratingDensitylmmEditField_2 = uieditfield(app.TransmissionGratingPanel, 'numeric');
            app.HorizontalGratingDensitylmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @HorizontalGratingDensitylmmEditField_2ValueChanged, true);
            app.d_xnmEditField_2Label = uilabel(app.TransmissionGratingPanel);
            app.d_xnmEditField_2Label.Text = 'd_x (nm)';
            app.d_xnmEditField_2 = uieditfield(app.TransmissionGratingPanel, 'numeric');
            app.d_xnmEditField_2.ValueChangedFcn = createCallbackFcn(app, @d_xnmEditField_2ValueChanged, true);
            app.VerticalGratingDensitylmmEditField_2Label = uilabel(app.TransmissionGratingPanel);
            app.VerticalGratingDensitylmmEditField_2Label.Enable = 'off';
            app.VerticalGratingDensitylmmEditField_2Label.Text = 'Vertical Grating Density (l/mm)';
            app.VerticalGratingDensitylmmEditField_2 = uieditfield(app.TransmissionGratingPanel, 'numeric');
            app.VerticalGratingDensitylmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @VerticalGratingDensitylmmEditField_2ValueChanged, true);
            app.VerticalGratingDensitylmmEditField_2.Editable = 'off';
            app.VerticalGratingDensitylmmEditField_2.Enable = 'off';
            app.d_ynmEditField_2Label = uilabel(app.TransmissionGratingPanel);
            app.d_ynmEditField_2Label.Enable = 'off';
            app.d_ynmEditField_2Label.Text = 'd_y (nm)';
            app.d_ynmEditField_2 = uieditfield(app.TransmissionGratingPanel, 'numeric');
            app.d_ynmEditField_2.ValueChangedFcn = createCallbackFcn(app, @d_ynmEditField_2ValueChanged, true);
            app.d_ynmEditField_2.Editable = 'off';
            app.d_ynmEditField_2.Enable = 'off';
            app.IntensityProfileDropDown_2Label = uilabel(app.TransmissionGratingPanel);
            app.IntensityProfileDropDown_2Label.Text = 'Intensity Profile';
            app.IntensityProfileDropDown_2 = uidropdown(app.TransmissionGratingPanel);
            app.IntensityProfileDropDown_2.Items = {'Uniform', 'Multiple-Slit'};
            app.IntensityProfileDropDown_2.ItemsData = {'1', '2'};
            app.IntensityProfileDropDown_2.ValueChangedFcn = createCallbackFcn(app, @IntensityProfileDropDown_2ValueChanged, true);
            app.IntensityProfileDropDown_2.Enable = 'off';
            app.IntensityProfileDropDown_2.Value = '1';
            app.HorizontalOpaqueSlitRatioEditField_2Label = uilabel(app.TransmissionGratingPanel);
            app.HorizontalOpaqueSlitRatioEditField_2Label.Visible = 'off';
            app.HorizontalOpaqueSlitRatioEditField_2Label.Text = 'Horizontal Opaque/Slit Ratio';
            app.HorizontalOpaqueSlitRatioEditField_2 = uieditfield(app.TransmissionGratingPanel, 'numeric');
            app.HorizontalOpaqueSlitRatioEditField_2.Visible = 'off';
            app.HorizontalOpaqueSlitRatioEditField_2.Value = 1;
            app.VerticalOpaqueSlitRatioEditField_2Label = uilabel(app.TransmissionGratingPanel);
            app.VerticalOpaqueSlitRatioEditField_2Label.Visible = 'off';
            app.VerticalOpaqueSlitRatioEditField_2Label.Text = 'Vertical Opaque/Slit Ratio';
            app.VerticalOpaqueSlitRatioEditField_2 = uieditfield(app.TransmissionGratingPanel, 'numeric');
            app.VerticalOpaqueSlitRatioEditField_2.Visible = 'off';
            app.VerticalOpaqueSlitRatioEditField_2.Value = 1;
            app.GratingTypeDropDownLabel = uilabel(app.HarmonicsTab);
            app.GratingTypeDropDownLabel.Text = 'Grating Type';
            app.GratingTypeDropDown = uidropdown(app.HarmonicsTab);
            app.GratingTypeDropDown.Items = {'Transmission', 'Conical'};
            app.GratingTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GratingTypeDropDownValueChanged, true);
            app.GratingTypeDropDown.Value = 'Transmission';
            app.HarmonicsPanel = uipanel(app.HarmonicsTab);
            app.HarmonicsPanel.Title = 'Harmonics';
            app.DrivenWavelengthnmEditFieldLabel = uilabel(app.HarmonicsPanel);
            app.DrivenWavelengthnmEditFieldLabel.Text = 'Driven Wavelength (nm)';
            app.DrivenWavelengthnmEditField = uieditfield(app.HarmonicsPanel, 'numeric');
            app.LowestOrderEditFieldLabel = uilabel(app.HarmonicsPanel);
            app.LowestOrderEditFieldLabel.Text = 'Lowest Order';
            app.LowestOrderEditField = uieditfield(app.HarmonicsPanel, 'numeric');
            app.LowestOrderEditField.RoundFractionalValues = 'on';
            app.LowestOrderEditField.Value = 1;
            app.HighestOrderEditFieldLabel = uilabel(app.HarmonicsPanel);
            app.HighestOrderEditFieldLabel.Text = 'Highest Order';
            app.HighestOrderEditField = uieditfield(app.HarmonicsPanel, 'numeric');
            app.HighestOrderEditField.RoundFractionalValues = 'on';
            app.HighestOrderEditField.Value = 1;
            app.CCDPanel = uipanel(app.HarmonicsTab);
            app.CCDPanel.Title = 'CCD';
            app.CCDWidthpxEditField_3Label = uilabel(app.CCDPanel);
            app.CCDWidthpxEditField_3Label.Text = 'CCD Width (px)';
            app.CCDWidthpxEditField_3 = uieditfield(app.CCDPanel, 'numeric');
            app.CCDHeightpxEditField_3Label = uilabel(app.CCDPanel);
            app.CCDHeightpxEditField_3Label.Text = 'CCD Height (px)';
            app.CCDHeightpxEditField_3 = uieditfield(app.CCDPanel, 'numeric');
            app.CCDPixelSizeumEditField_3Label = uilabel(app.CCDPanel);
            app.CCDPixelSizeumEditField_3Label.Text = 'CCD Pixel Size (um)';
            app.CCDPixelSizeumEditField_3 = uieditfield(app.CCDPanel, 'numeric');
            app.CCDDistancemmEditField_3Label = uilabel(app.CCDPanel);
            app.CCDDistancemmEditField_3Label.Text = 'CCD Distance (mm)';
            app.CCDDistancemmEditField_3 = uieditfield(app.CCDPanel, 'numeric');
            app.BeamsizeumEditField_3Label = uilabel(app.CCDPanel);
            app.BeamsizeumEditField_3Label.Text = 'Beam size (um)';
            app.BeamsizeumEditField_3 = uieditfield(app.CCDPanel, 'numeric');
            app.FirstorderdiffractiononlyCheckBox = uicheckbox(app.HarmonicsTab);
            app.FirstorderdiffractiononlyCheckBox.Enable = 'off';
            app.FirstorderdiffractiononlyCheckBox.Text = 'First-order diffraction only';
            app.FirstorderdiffractiononlyCheckBox.Value = true;
            app.PlotButton_3 = uibutton(app.HarmonicsTab, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.LabelHarmonicOrdersCheckBox = uicheckbox(app.HarmonicsTab);
            app.LabelHarmonicOrdersCheckBox.Text = 'Label Harmonic Orders';
            app.ConicalGratingPanel = uipanel(app.HarmonicsTab);
            app.ConicalGratingPanel.Enable = 'off';
            app.ConicalGratingPanel.Title = 'Conical Grating';
            app.ConicalGratingPanel.Visible = 'off';
            app.GratingDensitylmmEditField_2Label = uilabel(app.ConicalGratingPanel);
            app.GratingDensitylmmEditField_2Label.Text = 'Grating Density (l/mm)';
            app.GratingDensitylmmEditField_2 = uieditfield(app.ConicalGratingPanel, 'numeric');
            app.GratingDensitylmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @GratingDensitylmmEditField_2ValueChanged, true);
            app.dnmEditField_2Label = uilabel(app.ConicalGratingPanel);
            app.dnmEditField_2Label.Text = 'd (nm)';
            app.dnmEditField_2 = uieditfield(app.ConicalGratingPanel, 'numeric');
            app.dnmEditField_2.ValueChangedFcn = createCallbackFcn(app, @dnmEditField_2ValueChanged, true);
            app.IncidentAngledegEditField_2Label = uilabel(app.ConicalGratingPanel);
            app.IncidentAngledegEditField_2Label.Text = 'Incident Angle γ (deg)';
            app.IncidentAngledegEditField_2 = uieditfield(app.ConicalGratingPanel, 'numeric');
            app.IncidentAzimuthalAngledegEditField_2Label = uilabel(app.ConicalGratingPanel);
            app.IncidentAzimuthalAngledegEditField_2Label.Text = 'Incident Azimuthal Angle α (deg)';
            app.IncidentAzimuthalAngledegEditField_2 = uieditfield(app.ConicalGratingPanel, 'numeric');
            app.CenteratConeTopCheckBox_2 = uicheckbox(app.ConicalGratingPanel);
            app.CenteratConeTopCheckBox_2.Text = 'Center at Cone Top';
            app.PlotOptionsLabel_2 = uilabel(app.ConicalGratingPanel);
            app.PlotOptionsLabel_2.Text = 'Plot Options';
            app.RotationDropDown_2Label = uilabel(app.ConicalGratingPanel);
            app.RotationDropDown_2Label.Text = 'Rotation';
            app.RotationDropDown_2 = uidropdown(app.ConicalGratingPanel);
            app.RotationDropDown_2.Items = {'0°', '90°', '180°', '270°'};
            app.RotationDropDown_2.ItemsData = [0 90 180 270];
            app.RotationDropDown_2.Value = 0;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Grating_Utilities

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end